classdef LaserTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        CaptureTab        matlab.ui.container.Tab
        UIAxes            matlab.ui.control.UIAxes
        CAPTUREButton     matlab.ui.control.Button
        ANALYZEButton     matlab.ui.control.Button
        AngularDisplacementDegreesTextAreaLabel  matlab.ui.control.Label
        AngularDisplacementDegreesTextArea  matlab.ui.control.TextArea
        CurrentLaserPositionWorldSpacemmTextAreaLabel  matlab.ui.control.Label
        CurrentLaserPositionWorldSpacemmTextArea  matlab.ui.control.TextArea
        PreviousLaserPositionWorldSpacemmTextAreaLabel  matlab.ui.control.Label
        PreviousLaserPositionWorldSpacemmTextArea  matlab.ui.control.TextArea
        ConfigurationTab  matlab.ui.container.Tab
        PathtoCheckerboardImageEditFieldLabel  matlab.ui.control.Label
        PathtoCheckerboardImageEditField  matlab.ui.control.EditField
        PathtoCameraParametersEditFieldLabel  matlab.ui.control.Label
        PathtoCameraParametersEditField  matlab.ui.control.EditField
        CreatedbyEldrickMillaresLaserTrackerv20LastUpdated060417Label  matlab.ui.control.Label
    end

    properties (Access = public)
        cam                      % Webcam Object
        frame                    % Captured Image 
        currentPosition          % Current Centroid Position
        currentPositionRW        % Real World Coordinates
        previousPosition         % Previous Centroid Position
        previousPositionRW       % Real World Coordinates
        
        squareSize               % Size in mm of Checkerboard Square
        boardSize                % Size of board in [x,y] squares
        
        imagePoints              % Generated from calibration
        worldPoints              % Generated from board size and square size
        
        cameraParams             % Camera Parameters from calibration - must import
        referenceImage           % Checkerboard Reference Image - must import
        
        newOrigin                % Generated from undistortion
        
        R                        % Rotation Matrix
        t                        % Translation Matrix
    end

    methods (Access = private)

        % Code that executes after component creation
        function setup(app)
            % Reset Image Acquisition Toolbox
            cla(app.UIAxes);
            imaqreset;
            
            % Identify User Platform
            if ismac 
                % Mac IMAQ needs explicit memory delimitation
                imaqmex('feature','-limitPhysicalMemoryUsage', false); 
                app.cam = videoinput('macvideo',1);%, 'RGB24_1920x1080');    % If you change resolution, recalibrate!
            else 
                app.cam = videoinput('winvideo', 1);%, 'RGB24_2304x1536');
            end
            
            % Parameter Initialization
            app.currentPosition = [0 0];
            app.previousPosition = [0 0];
            app.squareSize = 23;
            app.boardSize = [7,10];
             
            % Load ref_image.mat as the reference checkerboard image in the plane of measurement
            struct = load('ref_image.mat');
            app.referenceImage = struct.testReference;
            % Load camera_params.mat as the calibrated parameters for the experiment. 
            struct = load('camera_params.mat');
            app.cameraParams = struct.cameraParams;
            
            % Generate extrinsic parameters (rotation and translation matrices) from calibration
            [app.imagePoints, app.boardSize] = detectCheckerboardPoints(app.referenceImage);
            app.worldPoints = generateCheckerboardPoints(app.boardSize, app.squareSize);
            [app.R, app.t] = extrinsics(app.imagePoints, app.worldPoints, app.cameraParams);
        end

        % Button pushed function: ANALYZEButton
        function ANALYZEButtonPushed(app, event)
            % Undistort captured image from calibration
            [app.frame, app.newOrigin] = undistortImage(app.frame, app.cameraParams, 'OutputView', 'full');
            
            % Green Channel Extraction
            greenChannel = app.frame(:,:,2);
            
            % Pixel Thresholding
            binaryImage = im2bw(greenChannel);
            
            % Fill BW images for continuity
            binaryImage = imfill(binaryImage, 'holes');
            
            % Label BW Regions
            labeledImage = bwlabel(binaryImage);
            
            % If any green regions identified...
            if(any(labeledImage(:)))
                
                % Find region with largest area, store pixels into list
                imageProperties = regionprops(logical(binaryImage), 'Area');
                areas = imageProperties.Area;
                [~, sortIndexes] = sort(areas, 'descend');
                biggestBlob = ismember(labeledImage, sortIndexes(1:1));
                binaryImage = biggestBlob > 0;
            
                % Find that area's weighted centroid from the list
                imageProperties = regionprops(binaryImage, 'PixelIdxList', 'PixelList');
                idx = imageProperties(1).PixelIdxList;
                sum_region = sum(greenChannel(idx));
                x = imageProperties(1).PixelList(:,1);
                y = imageProperties(1).PixelList(:,2);
                xbar = sum(x.*double(greenChannel(idx)))/ sum_region;
                ybar = sum(y.*double(greenChannel(idx)))/ sum_region;
                centroids = [xbar, ybar];
            
                % Display Image and Centroid
                cla(app.UIAxes);
                imshow(app.frame,'Parent',app.UIAxes);
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, centroids(:,1), centroids(:,2), 'b*')    % Plot Centroids
                plot(app.UIAxes, xbar, ybar, 'b*')    % Plot Centroids
                
                % Assign centroid values as current position
                app.currentPosition(1) = centroids(:,1);
                app.currentPosition(2) = centroids(:,2);   
                
                % Print Current Laser Position
                app.currentPositionRW = pointsToWorld(app.cameraParams, app.R, app.t, app.currentPosition);
                str = sprintf('X: %g \t Y: %g \n', app.currentPositionRW(1), app.currentPositionRW(2));   
                app.CurrentLaserPositionWorldSpacemmTextArea.Value = str;
                
                % Print previous laser position
                app.previousPositionRW = pointsToWorld(app.cameraParams, app.R, app.t, app.previousPosition);
                str = sprintf('X: %g \t Y: %g \n', app.previousPositionRW(1), app.previousPositionRW(2));   
                app.PreviousLaserPositionWorldSpacemmTextArea.Value = str;
            
                % Calculate Angular Displacement
                xdisplacement = app.currentPositionRW(1) - app.previousPositionRW(1);    % deltas between previous and current
                ydisplacement = app.currentPositionRW(2) - app.previousPositionRW(2);
                [~, cameraLocation] = extrinsicsToCameraPose(app.R, app.t);              % calculate camera distance to pixel
                distanceToCamera = abs(cameraLocation(3));                               % HEAVILY DEPENDENT ON GOOD CALIBRATION
                xangle = radtodeg(atan(xdisplacement / distanceToCamera));               % calculate angles with inverse tan
                yangle = radtodeg(atan(ydisplacement / distanceToCamera));               % basic right triangle trig >.>
                str = sprintf('X Angle: %g \t Y Angle: %g \n', xangle, yangle);        
                app.AngularDisplacementDegreesTextArea.Value = str;
            
                % Update Previous Laser Position
                app.previousPosition(1) = app.currentPosition(1);
                app.previousPosition(2) = app.currentPosition(2);
                
            else
                
                % No Centroid Found
                app.CurrentLaserPositionWorldSpacemmTextArea.Value = 'No Centroid Found!';
                
                % Update Previous Laser Position - needed to move forward last valid position
                app.previousPosition(1) = app.currentPosition(1);
                app.previousPosition(2) = app.currentPosition(2);
                str = sprintf('X: %g \t Y: %g \n', app.previousPosition(1), app.previousPosition(2));
                app.PreviousLaserPositionWorldSpacemmTextArea.Value = str;
                
            end
        end

        % Button pushed function: CAPTUREButton
        function CAPTUREButtonPushed(app, event)
            cla(app.UIAxes);                        % display capture
            app.frame = getsnapshot(app.cam);
            imshow(app.frame,'Parent',app.UIAxes);
            
            % TODO: Sometimes, you get a picture of the laser spot and it looks good
            % then you go to analyze it and it marks some other dumb spot as the largest area
            % a solution would be to first conduct your experiment in a controlled, light-isolated
            % area. If you're already doing that then its my fault - just add in 
            % the centroiding calculation and overlay code here - then you can preview where the 
            % centroid would be (properly over the laser spot!) before commiting to analyzing. 
            % I left it out for efficiency but if its a problem, you know what to do! :) 
        end

        % Value changed function: PathtoCameraParametersEditField
        function PathtoCameraParametersEditFieldValueChanged(app, event)
            struct = load(app.PathtoCameraParametersEditField.Value);    % need to do structs because dumb. 
            app.cameraParams = struct.cameraParams;
            % Be sure to name your .mat variables accordingly!!
        end

        % Value changed function: PathtoCheckerboardImageEditField
        function PathtoCheckerboardImageEditFieldValueChanged(app, event)
            struct = load(app.PathtoCheckerboardImageEditField.Value);
            app.referenceImage = struct.referenceImage;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UIFigure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CaptureTab = uitab(app.TabGroup);
            app.CaptureTab.Title = 'Capture';
            app.UIAxes = uiaxes(app.CaptureTab);
            title(app.UIAxes, 'Webcam Capture')
            app.CAPTUREButton = uibutton(app.CaptureTab, 'push');
            app.CAPTUREButton.ButtonPushedFcn = createCallbackFcn(app, @CAPTUREButtonPushed, true);
            app.CAPTUREButton.Text = 'CAPTURE';
            app.ANALYZEButton = uibutton(app.CaptureTab, 'push');
            app.ANALYZEButton.ButtonPushedFcn = createCallbackFcn(app, @ANALYZEButtonPushed, true);
            app.ANALYZEButton.Text = 'ANALYZE';
            app.AngularDisplacementDegreesTextAreaLabel = uilabel(app.CaptureTab);
            app.AngularDisplacementDegreesTextAreaLabel.Text = 'Angular Displacement (Degrees)';
            app.AngularDisplacementDegreesTextArea = uitextarea(app.CaptureTab);
            app.AngularDisplacementDegreesTextArea.Editable = 'off';
            app.CurrentLaserPositionWorldSpacemmTextAreaLabel = uilabel(app.CaptureTab);
            app.CurrentLaserPositionWorldSpacemmTextAreaLabel.Text = 'Current Laser Position (World Space, mm)';
            app.CurrentLaserPositionWorldSpacemmTextArea = uitextarea(app.CaptureTab);
            app.CurrentLaserPositionWorldSpacemmTextArea.Editable = 'off';
            app.PreviousLaserPositionWorldSpacemmTextAreaLabel = uilabel(app.CaptureTab);
            app.PreviousLaserPositionWorldSpacemmTextAreaLabel.Text = 'Previous Laser Position (World Space, mm)';
            app.PreviousLaserPositionWorldSpacemmTextArea = uitextarea(app.CaptureTab);
            app.PreviousLaserPositionWorldSpacemmTextArea.Editable = 'off';
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.PathtoCheckerboardImageEditFieldLabel = uilabel(app.ConfigurationTab);
            app.PathtoCheckerboardImageEditFieldLabel.Text = 'Path to Checkerboard Image';
            app.PathtoCheckerboardImageEditField = uieditfield(app.ConfigurationTab, 'text');
            app.PathtoCheckerboardImageEditField.ValueChangedFcn = createCallbackFcn(app, @PathtoCheckerboardImageEditFieldValueChanged, true);
            app.PathtoCheckerboardImageEditField.Value = '[Default]';
            app.PathtoCameraParametersEditFieldLabel = uilabel(app.ConfigurationTab);
            app.PathtoCameraParametersEditFieldLabel.Text = 'Path to Camera Parameters';
            app.PathtoCameraParametersEditField = uieditfield(app.ConfigurationTab, 'text');
            app.PathtoCameraParametersEditField.ValueChangedFcn = createCallbackFcn(app, @PathtoCameraParametersEditFieldValueChanged, true);
            app.PathtoCameraParametersEditField.Value = '[Default]';
            app.CreatedbyEldrickMillaresLaserTrackerv20LastUpdated060417Label = uilabel(app.ConfigurationTab);
            app.CreatedbyEldrickMillaresLaserTrackerv20LastUpdated060417Label.Text = {'Created by Eldrick Millares'; 'LaserTracker v2.0 '; 'Last Updated: 06/04/17'; 'Contact: eldrick@stanford..edu'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = LaserTracking

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @setup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end